classdef analyseUltrason < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANALYSE_ULTRASON           matlab.ui.Figure
        LoadUltrasoundButton       matlab.ui.control.Button
        PlotUltrasoundButton       matlab.ui.control.Button
        axe1                       matlab.ui.control.UIAxes
        SpectreUltrasonButton      matlab.ui.control.Button
        axe2                       matlab.ui.control.UIAxes
        FFTButton                  matlab.ui.control.Button
        axe3                       matlab.ui.control.UIAxes
        ModulationdultrasonButton  matlab.ui.control.Button
        ClearSoundButton           matlab.ui.control.Button
        PlotModulationButton       matlab.ui.control.Button
        axe4                       matlab.ui.control.UIAxes
        pagedaccueilButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadUltrasoundButton
        function LoadUltrasoundButtonPushed(app, event)

            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
           
        end

        % Button pushed function: PlotUltrasoundButton
        function PlotUltrasoundButtonPushed(app, event)
            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
            %la representation du signal
            plot(app.axe1,x);
        end

        % Button pushed function: SpectreUltrasonButton
        function SpectreUltrasonButtonPushed(app, event)
            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
            %la representation spectrale
            plot(app.axe2,abs(fft(x)));
        end

        % Button pushed function: ModulationdultrasonButton
        function ModulationdultrasonButtonPushed(app, event)
            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
            %modulation de fréquence
            fs = 1000; 
            fc = 200;  
            t = (0:1/fs)';
            fDev = 50;
            y = fmmod(x,fc,fs,fDev);
            sound(y,f);
        end

        % Button pushed function: ClearSoundButton
        function ClearSoundButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: PlotModulationButton
        function PlotModulationButtonPushed(app, event)
            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
            fs = 1000; 
            fc = 200;  
            t = (0:1/fs)';
            fDev = 50;
            y = fmmod(x,fc,fs,fDev);
            plot(app.axe4,y);
            xlabel(app.axe4,'Time (s)')
            ylabel(app.axe4,'Amplitude')
            legend(app.axe4,'Modulated Signal')
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            [x,f] = audioread('ultrason.mp3') ;
            sound(x,f);
            plot(app.axe3,abs(fftshift(x)));
        end

        % Button pushed function: pagedaccueilButton
        function pagedaccueilButtonPushed(app, event)
            close(app.ANALYSE_ULTRASON)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANALYSE_ULTRASON = uifigure('Visible', 'off');
            app.ANALYSE_ULTRASON.Name = 'UI Figure';
            app.LoadUltrasoundButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.LoadUltrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUltrasoundButtonPushed, true);
            app.LoadUltrasoundButton.Text = 'Load Ultrasound';
            app.PlotUltrasoundButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.PlotUltrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUltrasoundButtonPushed, true);
            app.PlotUltrasoundButton.Text = 'Plot Ultrasound';
            app.axe1 = uiaxes(app.ANALYSE_ULTRASON);
            title(app.axe1, {'ultrasound plot'; ''})
            xlabel(app.axe1, 'X')
            ylabel(app.axe1, 'Y')
            app.SpectreUltrasonButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.SpectreUltrasonButton.ButtonPushedFcn = createCallbackFcn(app, @SpectreUltrasonButtonPushed, true);
            app.SpectreUltrasonButton.Text = 'Spectre Ultrason';
            app.axe2 = uiaxes(app.ANALYSE_ULTRASON);
            title(app.axe2, 'spectre ultrason')
            xlabel(app.axe2, 'X')
            ylabel(app.axe2, 'Y')
            app.FFTButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.axe3 = uiaxes(app.ANALYSE_ULTRASON);
            title(app.axe3, {'fft'; ''})
            xlabel(app.axe3, 'X')
            ylabel(app.axe3, 'Y')
            app.ModulationdultrasonButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.ModulationdultrasonButton.ButtonPushedFcn = createCallbackFcn(app, @ModulationdultrasonButtonPushed, true);
            app.ModulationdultrasonButton.Text = 'Modulation d''ultrason';
            app.ClearSoundButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.ClearSoundButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSoundButtonPushed, true);
            app.ClearSoundButton.Text = 'Clear Sound';
            app.PlotModulationButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.PlotModulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModulationButtonPushed, true);
            app.PlotModulationButton.Text = 'Plot Modulation';
            app.axe4 = uiaxes(app.ANALYSE_ULTRASON);
            title(app.axe4, 'plot modulation')
            xlabel(app.axe4, 'X')
            ylabel(app.axe4, 'Y')
            app.pagedaccueilButton = uibutton(app.ANALYSE_ULTRASON, 'push');
            app.pagedaccueilButton.ButtonPushedFcn = createCallbackFcn(app, @pagedaccueilButtonPushed, true);
            app.pagedaccueilButton.Text = 'page d''accueil';
            % Show the figure after all components are created
            app.ANALYSE_ULTRASON.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyseUltrason

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANALYSE_ULTRASON)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANALYSE_ULTRASON)
        end
    end
end