classdef positionTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        POSITION_TRACKING   matlab.ui.Figure
        axe1                matlab.ui.control.UIAxes
        TRACKINGMODEButton  matlab.ui.control.Button
        PagedaccueilButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TRACKINGMODEButton
        function TRACKINGMODEButtonPushed(app, event)

            delete(instrfind); % supprimer les ports series
            theta = 0:(pi/180):pi;
            s = serial('COM6');
            s.BaudRate=115200;
            fopen(s);
            i = 0;
            
            inc = 1;
            
            while i<180
               A = fscanf(s);
               num(i+1) = str2num(A);
               i = i+1;
            end
            fclose(s)
            
            j = 1
            
            while j<181
                tab(j,1) = (j-1)*inc
                tab(j,2) = num(j)
                tab(j,3) = num(j)*cosd((j-1)*inc)
                tab(j,4) = num(j)*sind((j-1)*inc)
                j = j+1
            end
            %figure
            %polar(theta,num)
            
            plot(app.axe1,tab(:,3),tab(:,4))


        end

        % Button pushed function: PagedaccueilButton
        function PagedaccueilButtonPushed(app, event)
            close(app.POSITION_TRACKING);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.POSITION_TRACKING = uifigure('Visible', 'off');
            app.POSITION_TRACKING.Name = 'UI Figure';
            app.axe1 = uiaxes(app.POSITION_TRACKING);
            title(app.axe1, 'Title')
            xlabel(app.axe1, 'X')
            ylabel(app.axe1, 'Y')
            app.TRACKINGMODEButton = uibutton(app.POSITION_TRACKING, 'push');
            app.TRACKINGMODEButton.ButtonPushedFcn = createCallbackFcn(app, @TRACKINGMODEButtonPushed, true);
            app.TRACKINGMODEButton.Text = 'TRACKING MODE';
            app.PagedaccueilButton = uibutton(app.POSITION_TRACKING, 'push');
            app.PagedaccueilButton.ButtonPushedFcn = createCallbackFcn(app, @PagedaccueilButtonPushed, true);
            app.PagedaccueilButton.Text = 'Page d''accueil';
            % Show the figure after all components are created
            app.POSITION_TRACKING.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = positionTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.POSITION_TRACKING)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.POSITION_TRACKING)
        end
    end
end